classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        IntroTab                    matlab.ui.container.Tab
        Image                       matlab.ui.control.Image
        RIGELSLabel                 matlab.ui.control.Label
        presentaLabel               matlab.ui.control.Label
        ESTRELLITALabel             matlab.ui.control.Label
        CinDirTab                   matlab.ui.container.Tab
        ModomanualPanel             matlab.ui.container.Panel
        MOTOR1SliderLabel           matlab.ui.control.Label
        MOTOR1Slider                matlab.ui.control.Slider
        MOTOR2SliderLabel           matlab.ui.control.Label
        MOTOR2Slider                matlab.ui.control.Slider
        MOTOR3SliderLabel           matlab.ui.control.Label
        MOTOR3Slider                matlab.ui.control.Slider
        POSICINDELEFECTORLabel      matlab.ui.control.Label
        CoordenadasfinalesPanel     matlab.ui.container.Panel
        XLabel                      matlab.ui.control.Label
        YLabel                      matlab.ui.control.Label
        ZLabel                      matlab.ui.control.Label
        posicinenXLabel             matlab.ui.control.Label
        posicinenYLabel             matlab.ui.control.Label
        posicinenZLabel             matlab.ui.control.Label
        CinemticaDirectaPanel       matlab.ui.container.Panel
        Thera1EditFieldLabel        matlab.ui.control.Label
        Theta1Dir                   matlab.ui.control.NumericEditField
        Thera2EditFieldLabel        matlab.ui.control.Label
        Theta2Dir                   matlab.ui.control.NumericEditField
        Thera3EditFieldLabel        matlab.ui.control.Label
        Theta3Dir                   matlab.ui.control.NumericEditField
        MOVEButton                  matlab.ui.control.Button
        HOMEButton                  matlab.ui.control.Button
        axesDir                     matlab.ui.control.UIAxes
        CinInvTab                   matlab.ui.container.Tab
        CinemticaInversaPanel       matlab.ui.container.Panel
        XEditFieldLabel             matlab.ui.control.Label
        Xinv                        matlab.ui.control.NumericEditField
        PosicindeseadadelefectorfinalLabel  matlab.ui.control.Label
        MOVERButton                 matlab.ui.control.Button
        YEditFieldLabel             matlab.ui.control.Label
        Yinv                        matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        Zinv                        matlab.ui.control.NumericEditField
        PosiblessolucionesDropDownLabel  matlab.ui.control.Label
        PosiblessolucionesDropDown  matlab.ui.control.DropDown
        ngulosporjuntaPanel         matlab.ui.container.Panel
        Thera1EditField_2Label      matlab.ui.control.Label
        Theta1Inv                   matlab.ui.control.NumericEditField
        Thera2EditField_2Label      matlab.ui.control.Label
        Theta2Inv                   matlab.ui.control.NumericEditField
        Thera3EditField_2Label      matlab.ui.control.Label
        Theta3Inv                   matlab.ui.control.NumericEditField
        axesInv                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PosiblessolucionesDropDown
        function PosiblessolucionesDropDownValueChanged(app, event)
            value = app.PosiblessolucionesDropDown.Value
            PX = getGlobal_PX;
            PY = getGlobal_PY;
            PZ = getGlobal_PZ;
            [S1,S2,S3,S4]=IGM(PX,PY,PZ);
            S1
            S2
            S3
            S4
            S1 = rad2deg(S1)
            S2 = rad2deg(S2)
            S3 = rad2deg(S3)
            S4 = rad2deg(S4)
            t1 = getGlobal_theta1;t2 = getGlobal_theta2;t3 = getGlobal_theta3;
            %Aquí va el if de la solución seeccionada
            if (value == "Solución 1")
                x = "SOL UNOOO"
                %[theta1, theta2, theta3] = setGlobal_thetas(t1+S1(1),t2+S1(2),t3+S1(3));
                [theta1, theta2, theta3] = setGlobal_thetas(S1(1),S1(2),S1(3));
            elseif (value == "Solución 2")
                %[theta1, theta2, theta3] = setGlobal_thetas(t1+S2(1),t2+S2(2),t3+S2(3));
                [theta1, theta2, theta3] = setGlobal_thetas(S2(1),S2(2),S2(3));
            elseif (value == "Solución 3")
                %[theta1, theta2, theta3] = setGlobal_thetas(t1+S3(1),t2+S3(2),t3+S3(3));
                [theta1, theta2, theta3] = setGlobal_thetas(S3(1),S3(2),S3(3));
            elseif (value == "Solución 4")
                %[theta1, theta2, theta3] = setGlobal_thetas(t1+S4(1),t2+S4(2),t3+S4(3));
                [theta1, theta2, theta3] = setGlobal_thetas(S4(1),S4(2),S4(3));
            end       
            %Compensacion en punto
            setGlobal_theta3(getGlobal_theta3-90);
            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));
            
            %Para cambiar graficamente el slider
%             app.MOTOR1Slider.Value = getGlobal_theta1;
%             app.MOTOR2Slider.Value = getGlobal_theta2;
%             app.MOTOR3Slider.Value = getGlobal_theta3+90;
            %Thetas en Dir e Inv
            app.Theta1Dir.Value = getGlobal_theta1; %--Dir
            app.Theta2Dir.Value = getGlobal_theta2;
            app.Theta3Dir.Value = getGlobal_theta3+90; %Debería salir 0 o -90? 
            app.Theta1Inv.Value = getGlobal_theta1; %--Inv
            app.Theta2Inv.Value = getGlobal_theta2;
            app.Theta3Inv.Value = getGlobal_theta3+90;
            %-----------------------2)Datos al Arduino
            device=serialport("COM5",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device 
        end

        % Button pushed function: MOVERButton
        function MOVERButtonPushed(app, event)
            setGlobal_PX(app.Xinv.Value);
            setGlobal_PY(app.Yinv.Value);
            setGlobal_PZ(app.Zinv.Value);
        end

        % Button pushed function: MOVEButton
        function MOVEButtonPushed(app, event)
            theta1 = app.Theta1Dir.Value;
            theta2 = app.Theta2Dir.Value;
            theta3 = app.Theta3Dir.Value;
            setGlobal_theta1(theta1);
            setGlobal_theta2(theta2);
            setGlobal_theta3(theta3-90);

            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));
            
            %Para cambiar graficamente el slider
            app.MOTOR1Slider.Value = getGlobal_theta1;
            app.MOTOR2Slider.Value = getGlobal_theta2;
            app.MOTOR3Slider.Value = getGlobal_theta3+90;

            app.Theta1Inv.Value = getGlobal_theta1; %--Inv
            app.Theta2Inv.Value = getGlobal_theta2;
            app.Theta3Inv.Value = getGlobal_theta3+90;
            
            %-----------------------2)Datos al Arduino
            device=serialport("COM5",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            [theta1, theta2, theta3] = setGlobal_thetas(0,0,0);
            %Compensacion en punto
            setGlobal_theta3(getGlobal_theta3-90);
            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));
            
            %Para cambiar graficamente el slider
            app.MOTOR1Slider.Value = getGlobal_theta1;
            app.MOTOR2Slider.Value = getGlobal_theta2;
            app.MOTOR3Slider.Value = getGlobal_theta3+90;
            %Thetas en Dir e Inv
            app.Theta1Dir.Value = getGlobal_theta1; %--Dir
            app.Theta2Dir.Value = getGlobal_theta2;
            app.Theta3Dir.Value = getGlobal_theta3+90; %Debería salir 0 o -90? 
            app.Theta1Inv.Value = getGlobal_theta1; %--Inv
            app.Theta2Inv.Value = getGlobal_theta2;
            app.Theta3Inv.Value = getGlobal_theta3+90;
            %-----------------------2)Datos al Arduino
            device=serialport("COM5",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device      
            
        end

        % Value changed function: MOTOR1Slider
        function MOTOR1SliderValueChanged(app, event)
            theta1 = app.MOTOR1Slider.Value; %Deberia hacer uno con *changing func
            setGlobal_theta1(theta1);

            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            app.Theta1Dir.Value = getGlobal_theta1; %--Dir
            app.Theta1Inv.Value = getGlobal_theta1; %--Inv
            
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));

                        
            %-----------------------2)Datos al Arduino
            device=serialport("COM5",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device
            
        end

        % Value changed function: MOTOR2Slider
        function MOTOR2SliderValueChanged(app, event)
            theta2 = app.MOTOR2Slider.Value;
            setGlobal_theta2(theta2);

            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            app.Theta2Dir.Value = getGlobal_theta2; %--Dir
            app.Theta2Inv.Value = getGlobal_theta2; %--Inv
            
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));

                        
            %-----------------------2)Datos al Arduino
            device=serialport("COM5",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device
        end

        % Value changed function: MOTOR3Slider
        function MOTOR3SliderValueChanged(app, event)
            theta3 = app.MOTOR3Slider.Value;
            setGlobal_theta3(theta3);

            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            app.Theta3Dir.Value = getGlobal_theta3; %--Dir
            app.Theta3Inv.Value = getGlobal_theta3; %--Inv
            
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));

                        
            %-----------------------2)Datos al Arduino
            device=serialport("COM5",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.Image = uiimage(app.IntroTab);
            app.Image.ImageSource = 'C:\Users\Vivi\OneDrive - Instituto Tecnologico y de Estudios Superiores de Monterrey\DECIMO SEMESTRE\Robotica\GUI2\estrellitas.jpg';
            app.RIGELSLabel = uilabel(app.IntroTab);
            app.RIGELSLabel.Text = 'R I G E L S';
            app.presentaLabel = uilabel(app.IntroTab);
            app.presentaLabel.Text = 'presenta:';
            app.ESTRELLITALabel = uilabel(app.IntroTab);
            app.ESTRELLITALabel.Text = '" E S T R E L L I T A "';
            app.CinDirTab = uitab(app.TabGroup);
            app.CinDirTab.Title = 'Cin Dir';
            app.ModomanualPanel = uipanel(app.CinDirTab);
            app.ModomanualPanel.Title = 'Modo manual';
            app.MOTOR1SliderLabel = uilabel(app.ModomanualPanel);
            app.MOTOR1SliderLabel.Text = 'MOTOR 1:';
            app.MOTOR1Slider = uislider(app.ModomanualPanel);
            app.MOTOR1Slider.Limits = [0 180];
            app.MOTOR1Slider.ValueChangedFcn = createCallbackFcn(app, @MOTOR1SliderValueChanged, true);
            app.MOTOR2SliderLabel = uilabel(app.ModomanualPanel);
            app.MOTOR2SliderLabel.Text = 'MOTOR 2:';
            app.MOTOR2Slider = uislider(app.ModomanualPanel);
            app.MOTOR2Slider.Limits = [0 90];
            app.MOTOR2Slider.ValueChangedFcn = createCallbackFcn(app, @MOTOR2SliderValueChanged, true);
            app.MOTOR3SliderLabel = uilabel(app.ModomanualPanel);
            app.MOTOR3SliderLabel.Text = 'MOTOR 3:';
            app.MOTOR3Slider = uislider(app.ModomanualPanel);
            app.MOTOR3Slider.Limits = [0 180];
            app.MOTOR3Slider.ValueChangedFcn = createCallbackFcn(app, @MOTOR3SliderValueChanged, true);
            app.POSICINDELEFECTORLabel = uilabel(app.CinDirTab);
            app.POSICINDELEFECTORLabel.Text = 'POSICIÓN DEL EFECTOR';
            app.CoordenadasfinalesPanel = uipanel(app.CinDirTab);
            app.CoordenadasfinalesPanel.Title = 'Coordenadas finales';
            app.XLabel = uilabel(app.CoordenadasfinalesPanel);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.CoordenadasfinalesPanel);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.CoordenadasfinalesPanel);
            app.ZLabel.Text = 'Z:';
            app.posicinenXLabel = uilabel(app.CoordenadasfinalesPanel);
            app.posicinenXLabel.Text = 'posición en X';
            app.posicinenYLabel = uilabel(app.CoordenadasfinalesPanel);
            app.posicinenYLabel.Text = 'posición en Y';
            app.posicinenZLabel = uilabel(app.CoordenadasfinalesPanel);
            app.posicinenZLabel.Text = 'posición en Z';
            app.CinemticaDirectaPanel = uipanel(app.CinDirTab);
            app.CinemticaDirectaPanel.Title = 'Cinemática Directa';
            app.Thera1EditFieldLabel = uilabel(app.CinemticaDirectaPanel);
            app.Thera1EditFieldLabel.Text = 'Thera 1';
            app.Theta1Dir = uieditfield(app.CinemticaDirectaPanel, 'numeric');
            app.Theta1Dir.Limits = [0 90];
            app.Thera2EditFieldLabel = uilabel(app.CinemticaDirectaPanel);
            app.Thera2EditFieldLabel.Text = 'Thera 2';
            app.Theta2Dir = uieditfield(app.CinemticaDirectaPanel, 'numeric');
            app.Theta2Dir.Limits = [0 90];
            app.Thera3EditFieldLabel = uilabel(app.CinemticaDirectaPanel);
            app.Thera3EditFieldLabel.Text = 'Thera 3';
            app.Theta3Dir = uieditfield(app.CinemticaDirectaPanel, 'numeric');
            app.Theta3Dir.Limits = [0 180];
            app.MOVEButton = uibutton(app.CinemticaDirectaPanel, 'push');
            app.MOVEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEButtonPushed, true);
            app.MOVEButton.Text = 'MOVE';
            app.HOMEButton = uibutton(app.CinDirTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.axesDir = uiaxes(app.CinDirTab);
            xlabel(app.axesDir, 'X')
            ylabel(app.axesDir, 'Y')
            zlabel(app.axesDir, 'Z')
            app.CinInvTab = uitab(app.TabGroup);
            app.CinInvTab.Title = 'Cin Inv';
            app.CinemticaInversaPanel = uipanel(app.CinInvTab);
            app.CinemticaInversaPanel.Title = 'Cinemática Inversa';
            app.XEditFieldLabel = uilabel(app.CinemticaInversaPanel);
            app.XEditFieldLabel.Text = 'X';
            app.Xinv = uieditfield(app.CinemticaInversaPanel, 'numeric');
            app.PosicindeseadadelefectorfinalLabel = uilabel(app.CinemticaInversaPanel);
            app.PosicindeseadadelefectorfinalLabel.Text = 'Posición deseada del efector final:';
            app.MOVERButton = uibutton(app.CinemticaInversaPanel, 'push');
            app.MOVERButton.ButtonPushedFcn = createCallbackFcn(app, @MOVERButtonPushed, true);
            app.MOVERButton.Text = 'MOVER';
            app.YEditFieldLabel = uilabel(app.CinemticaInversaPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.Yinv = uieditfield(app.CinemticaInversaPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.CinemticaInversaPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.Zinv = uieditfield(app.CinemticaInversaPanel, 'numeric');
            app.PosiblessolucionesDropDownLabel = uilabel(app.CinInvTab);
            app.PosiblessolucionesDropDownLabel.Text = 'Posibles soluciones';
            app.PosiblessolucionesDropDown = uidropdown(app.CinInvTab);
            app.PosiblessolucionesDropDown.Items = {'Seleccione', 'Solución 1', 'Solución 2', 'Solución 3', 'Solución 4'};
            app.PosiblessolucionesDropDown.ValueChangedFcn = createCallbackFcn(app, @PosiblessolucionesDropDownValueChanged, true);
            app.PosiblessolucionesDropDown.Value = 'Seleccione';
            app.ngulosporjuntaPanel = uipanel(app.CinInvTab);
            app.ngulosporjuntaPanel.Title = 'Ángulos por junta';
            app.Thera1EditField_2Label = uilabel(app.ngulosporjuntaPanel);
            app.Thera1EditField_2Label.Text = 'Thera 1';
            app.Theta1Inv = uieditfield(app.ngulosporjuntaPanel, 'numeric');
            app.Theta1Inv.Editable = 'off';
            app.Thera2EditField_2Label = uilabel(app.ngulosporjuntaPanel);
            app.Thera2EditField_2Label.Text = 'Thera 2';
            app.Theta2Inv = uieditfield(app.ngulosporjuntaPanel, 'numeric');
            app.Theta2Inv.Editable = 'off';
            app.Thera3EditField_2Label = uilabel(app.ngulosporjuntaPanel);
            app.Thera3EditField_2Label.Text = 'Thera 3';
            app.Theta3Inv = uieditfield(app.ngulosporjuntaPanel, 'numeric');
            app.Theta3Inv.Editable = 'off';
            app.axesInv = uiaxes(app.CinInvTab);
            xlabel(app.axesInv, 'X')
            ylabel(app.axesInv, 'Y')
            zlabel(app.axesInv, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end